classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        sdnnLabel                 matlab.ui.control.Label
        SDNNLabel                 matlab.ui.control.Label
        MomentanpulsCheckBox      matlab.ui.control.CheckBox
        TidsfnsterEditField       matlab.ui.control.NumericEditField
        TidsfnsterEditFieldLabel  matlab.ui.control.Label
        VljfilButton              matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ecg_data % Description
        timestamps
        freq % Description
        mode = "momentan" % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VljfilButton
        function VljfilButtonPushed(app, event)
            [file, path] = uigetfile("*.mat")
            S = load(file);
            app.ecg_data = load_ecg_var("LL_RA", S);

            %app.ecg_data = load_ecg_var("LL_RA", S);
            %app.ecg_data = Shimmer_9DF2_ECG_LL_RA_24BIT_CAL;
            %app.ecg_data = Shimmer_9DF2_ECG_LL_LA_24BIT_CAL;
            %app.ecg_data = Shimmer_9DF2_ECG_Vx_RL_24BIT_CAL;
      
            %app.timestamps = Shimmer_9DF2_TimestampSync_Unix_CAL;
            app.timestamps = load_ecg_var("TimestampSync", S);
            app.freq = get_freq(app.timestamps);

             app.ecg_data = preproc(app.ecg_data, app.freq);

            app.MomentanpulsCheckBox.Visible = 'on';
            app.TidsfnsterEditField.Visible = 'on';
            app.SDNNLabel.Visible = 'on';

            if app.mode == "momentan"
                MomentanpulsCheckBoxValueChanged(app, event);
            elseif app.mode == "tidsfönster"
                TidsfnsterEditFieldValueChanged(app, event);
            end
                
            %plot(app.UIAxes, app.timestamps)
        end

        % Value changed function: TidsfnsterEditField
        function TidsfnsterEditFieldValueChanged(app, event)
            value = app.TidsfnsterEditField.Value;
            ecg_data = app.ecg_data;
            %ecg_data = ecg_data-movmean(ecg_data,500);
            %[pulse, indices] = momentan_puls(ecg_data, app.timestamps, 0.6, 12);
            [pulse, indices] = pulse_time_window(ecg_data, app.timestamps, 0.15, app.TidsfnsterEditField.Value);
            remove_outliers(pulse);
            plot(app.UIAxes, indices/512, pulse, "r");
            app.UIAxes.XLim = [0 600];
            grid(app.UIAxes, 'on')
            app.mode = "tidsfönster";
        end

        % Value changed function: MomentanpulsCheckBox
        function MomentanpulsCheckBoxValueChanged(app, event)
            value = app.MomentanpulsCheckBox.Value;
            if value == 1
                app.TidsfnsterEditField.Visible = 'off';
                ecg_data = app.ecg_data;

                %ecg_data = ecg_data-movmean(ecg_data,1000);
                %ecg_data = scale_ecg(ecg_data, 2000);

                %ecg_data = ecg_data-movmean(ecg_data,500);
                %[pulse, indices] = momentan_puls(ecg_data, app.timestamps, 0.6, 12);
                [pulse, indices] = momentan_puls(ecg_data, app.timestamps, 0.15, 1);
                
                pulse = remove_outliers(pulse);
                
                plot(app.UIAxes, indices/512, pulse, "r");
                app.UIAxes.XLim = [0 600];
                grid(app.UIAxes, 'on')
            else
                app.TidsfnsterEditField.Visible = 'on';
            end
            app.mode = "momentan";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Puls över tid')
            xlabel(app.UIAxes, 't/s')
            ylabel(app.UIAxes, 'puls')
            zlabel(app.UIAxes, 'Z')
            app.VljfilButton = uibutton(app.UIFigure, 'push');
            app.VljfilButton.ButtonPushedFcn = createCallbackFcn(app, @VljfilButtonPushed, true);
            app.VljfilButton.Text = 'Välj fil';
            app.TidsfnsterEditFieldLabel = uilabel(app.UIFigure);
            app.TidsfnsterEditFieldLabel.Visible = 'off';
            app.TidsfnsterEditFieldLabel.Text = 'Tidsfönster';
            app.TidsfnsterEditField = uieditfield(app.UIFigure, 'numeric');
            app.TidsfnsterEditField.ValueChangedFcn = createCallbackFcn(app, @TidsfnsterEditFieldValueChanged, true);
            app.TidsfnsterEditField.Visible = 'off';
            app.MomentanpulsCheckBox = uicheckbox(app.UIFigure);
            app.MomentanpulsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MomentanpulsCheckBoxValueChanged, true);
            app.MomentanpulsCheckBox.Visible = 'off';
            app.MomentanpulsCheckBox.Text = 'Momentanpuls';
            app.SDNNLabel = uilabel(app.UIFigure);
            app.SDNNLabel.Visible = 'off';
            app.SDNNLabel.Text = 'SDNN';
            app.sdnnLabel = uilabel(app.UIFigure);
            app.sdnnLabel.Visible = 'off';
            app.sdnnLabel.Text = 'sdnn';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end